classdef cm_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        StartAEDataAcquisitor_Button   matlab.ui.control.Button
        AENCMSystem_Label              matlab.ui.control.Label
        TrainAENFeatureExtractor_Button  matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        StopAEDataAcquisitor_Button    matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        AEDataAcquisitorTab            matlab.ui.container.Tab
        RawAEDataPanel                 matlab.ui.container.Panel
        RawAE_UIAxes                   matlab.ui.control.UIAxes
        SettingsforAEAcquisitorPanel   matlab.ui.container.Panel
        SampleRateField_Label          matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        UseSimulatedDataStreamSwitchLabel  matlab.ui.control.Label
        UseSimulatedDataStream_Switch  matlab.ui.control.Switch
        SelectmatFolder_Button         matlab.ui.control.Button
        SelectedFolder_Label           matlab.ui.control.Label
        NumberOfFiIesField_Label       matlab.ui.control.Label
        NumberOfFiles_Label            matlab.ui.control.Label
        FeatureExtractorTab            matlab.ui.container.Tab
        FeatureExtractorFigurePanel    matlab.ui.container.Panel
        FeatureExtractor_UIAxes        matlab.ui.control.UIAxes
        SettingsforFeatureExtractorPanel  matlab.ui.container.Panel
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        ClusterBoundaryTrackingTab     matlab.ui.container.Tab
        ClusterTabGroup                matlab.ui.container.TabGroup
        ClusterStatesTab               matlab.ui.container.Tab
        ClusterState_UIAxes            matlab.ui.control.UIAxes
        ClusterTransitionsTab          matlab.ui.container.Tab
        ClusterTransition_UIAxes       matlab.ui.control.UIAxes
        SettingsforClusterBoundaryTrackingPanel  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % CM System objects
    properties (Access = private)
        dataAcquisitor = DataAcquisitor.empty;
        fixedWindowSegmenter = FixedWindowSegmenter.empty;
        aenFeatureExtractor = AutoencoderExtractor.empty;
        clusterBoundaryTrackingModeler = ClusterBoundaryTrackingModeler.empty;
        
        % plot handles
        rawAEPlotHandle;
        
        isRunningDataAcquisitor = false;
    end
    
    methods (Access = private)        
        function initCMSystemObjects(app)
            
            %daq = AEAcquisitor();
            daq = SineGenAcquisitor(10, 100);
            % raw ae plot
            
            mwp = MovingWindowPlotter(true, 1000);
            % generate plot handle
            app.rawAEPlotHandle = plot(app.RawAE_UIAxes, NaN);            
            xlim(app.RawAE_UIAxes, [0, mwp.maxWindowSize]) 
            mwp.setPlotHandle(app.rawAEPlotHandle);
            daq.addObserver(mwp);
            
            app.dataAcquisitor = daq;
            %app.fixedWindowSegmenter = FixedWindowSegmenter();
            %app.aenFeatureExtractor = AutoencoderExtractor();
            %app.clusterBoundaryTrackingModeler = ClusterBoundaryTrackingModeler();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initCMSystemObjects();            
        end

        % Button pushed function: SelectmatFolder_Button
        function SelectmatFolder_ButtonPushed(app, event)
            selectedFolder = uigetdir("", "Select Folder with mat files containing the data stream to simulate:");
            app.SelectedFolder_Label.Text = selectedFolder;
            files = DataParser.getFilesInFolders(selectedFolder, 'mat');
            app.NumberOfFiles_Label.Text = num2str(length(files));
            matParser = DataParser('FileType', 'mat');
            %app.dataAcquisitor = SimStreamAcquisitor(matParser, files, 1e6, 1e6);
        end

        % Button pushed function: StartAEDataAcquisitor_Button
        function StartAEDataAcquisitor_ButtonPushed(app, event)
            app.isRunningDataAcquisitor = true;
            while app.isRunningDataAcquisitor
                app.dataAcquisitor.update();               
            end
        end

        % Button pushed function: StopAEDataAcquisitor_Button
        function StopAEDataAcquisitor_ButtonPushed(app, event)
            app.isRunningDataAcquisitor = false;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {765, 765};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {209, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartAEDataAcquisitor_Button = uibutton(app.LeftPanel, 'push');
            app.StartAEDataAcquisitor_Button.ButtonPushedFcn = createCallbackFcn(app, @StartAEDataAcquisitor_ButtonPushed, true);
            app.StartAEDataAcquisitor_Button.Text = 'Start AEDataAcquisitor';
            app.AENCMSystem_Label = uilabel(app.LeftPanel);
            app.AENCMSystem_Label.Text = 'AEN-CMSystem';
            app.TrainAENFeatureExtractor_Button = uibutton(app.LeftPanel, 'push');
            app.TrainAENFeatureExtractor_Button.Text = 'Train AEN FeatureExtractor';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.Text = '...';
            app.StopAEDataAcquisitor_Button = uibutton(app.LeftPanel, 'push');
            app.StopAEDataAcquisitor_Button.ButtonPushedFcn = createCallbackFcn(app, @StopAEDataAcquisitor_ButtonPushed, true);
            app.StopAEDataAcquisitor_Button.Text = 'Stop AEDataAcquisitor';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.AEDataAcquisitorTab = uitab(app.TabGroup);
            app.AEDataAcquisitorTab.Title = 'AEDataAcquisitor';
            app.RawAEDataPanel = uipanel(app.AEDataAcquisitorTab);
            app.RawAEDataPanel.Title = 'Raw AE Data';
            app.RawAE_UIAxes = uiaxes(app.RawAEDataPanel);
            title(app.RawAE_UIAxes, 'Raw Data')
            xlabel(app.RawAE_UIAxes, 'Time [s]')
            ylabel(app.RawAE_UIAxes, 'Voltage [Bits]')
            zlabel(app.RawAE_UIAxes, 'Z')
            app.SettingsforAEAcquisitorPanel = uipanel(app.AEDataAcquisitorTab);
            app.SettingsforAEAcquisitorPanel.Title = 'Settings for AEAcquisitor';
            app.SampleRateField_Label = uilabel(app.SettingsforAEAcquisitorPanel);
            app.SampleRateField_Label.Text = 'SampleRate: 2e6 Hz';
            app.Label = uilabel(app.SettingsforAEAcquisitorPanel);
            app.Label.Text = 'Label:';
            app.UseSimulatedDataStreamSwitchLabel = uilabel(app.SettingsforAEAcquisitorPanel);
            app.UseSimulatedDataStreamSwitchLabel.Text = 'Use SimulatedDataStream';
            app.UseSimulatedDataStream_Switch = uiswitch(app.SettingsforAEAcquisitorPanel, 'slider');
            app.UseSimulatedDataStream_Switch.Items = {'false', 'true'};
            app.UseSimulatedDataStream_Switch.Value = 'false';
            app.SelectmatFolder_Button = uibutton(app.SettingsforAEAcquisitorPanel, 'push');
            app.SelectmatFolder_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectmatFolder_ButtonPushed, true);
            app.SelectmatFolder_Button.Text = 'Select *.mat Folder:';
            app.SelectedFolder_Label = uilabel(app.SettingsforAEAcquisitorPanel);
            app.NumberOfFiIesField_Label = uilabel(app.SettingsforAEAcquisitorPanel);
            app.NumberOfFiIesField_Label.Text = 'Number of FiIes:';
            app.NumberOfFiles_Label = uilabel(app.SettingsforAEAcquisitorPanel);
            app.NumberOfFiles_Label.Text = '-';
            app.FeatureExtractorTab = uitab(app.TabGroup);
            app.FeatureExtractorTab.Title = 'FeatureExtractor';
            app.FeatureExtractorFigurePanel = uipanel(app.FeatureExtractorTab);
            app.FeatureExtractorFigurePanel.Title = 'Feature Extractor Data';
            app.FeatureExtractor_UIAxes = uiaxes(app.FeatureExtractorFigurePanel);
            title(app.FeatureExtractor_UIAxes, 'Title')
            xlabel(app.FeatureExtractor_UIAxes, 'X')
            ylabel(app.FeatureExtractor_UIAxes, 'Y')
            zlabel(app.FeatureExtractor_UIAxes, 'Z')
            app.SettingsforFeatureExtractorPanel = uipanel(app.FeatureExtractorTab);
            app.SettingsforFeatureExtractorPanel.Title = 'Settings for FeatureExtractor';
            app.Label_2 = uilabel(app.SettingsforFeatureExtractorPanel);
            app.Label_2.Text = 'Label:';
            app.Label_3 = uilabel(app.SettingsforFeatureExtractorPanel);
            app.Label_3.Text = 'Label:';
            app.Label_4 = uilabel(app.SettingsforFeatureExtractorPanel);
            app.Label_4.Text = 'Label:';
            app.Label_5 = uilabel(app.SettingsforFeatureExtractorPanel);
            app.Label_5.Text = 'Label:';
            app.ClusterBoundaryTrackingTab = uitab(app.TabGroup);
            app.ClusterBoundaryTrackingTab.Title = 'ClusterBoundaryTracking';
            app.ClusterTabGroup = uitabgroup(app.ClusterBoundaryTrackingTab);
            app.ClusterStatesTab = uitab(app.ClusterTabGroup);
            app.ClusterStatesTab.Title = 'ClusterStates';
            app.ClusterState_UIAxes = uiaxes(app.ClusterStatesTab);
            title(app.ClusterState_UIAxes, 'Title')
            xlabel(app.ClusterState_UIAxes, 'X')
            ylabel(app.ClusterState_UIAxes, 'Y')
            zlabel(app.ClusterState_UIAxes, 'Z')
            app.ClusterTransitionsTab = uitab(app.ClusterTabGroup);
            app.ClusterTransitionsTab.Title = 'ClusterTransitions';
            app.ClusterTransition_UIAxes = uiaxes(app.ClusterTransitionsTab);
            title(app.ClusterTransition_UIAxes, 'Title')
            xlabel(app.ClusterTransition_UIAxes, 'X')
            ylabel(app.ClusterTransition_UIAxes, 'Y')
            zlabel(app.ClusterTransition_UIAxes, 'Z')
            app.SettingsforClusterBoundaryTrackingPanel = uipanel(app.ClusterBoundaryTrackingTab);
            app.SettingsforClusterBoundaryTrackingPanel.Title = 'Settings for ClusterBoundaryTracking';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cm_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end